classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                          matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MENU1VR                     matlab.ui.container.Tab
        TitleMENU1                  matlab.ui.control.Label
        SubtitleMENU1               matlab.ui.control.Label
        InsiraumafunoyfxLabel       matlab.ui.control.Label
        funcao1                     matlab.ui.control.EditField
        aEditFieldLabel             matlab.ui.control.Label
        a_field1                    matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        b_field1                    matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        n_field1                    matlab.ui.control.NumericEditField
        CalcularBotao1VR            matlab.ui.control.Button
        MtodosDropDownLabel         matlab.ui.control.Label
        Metodos1                    matlab.ui.control.DropDown
        CDI_Botoes1                 matlab.ui.container.ButtonGroup
        CalculoIntegral1            matlab.ui.control.ToggleButton
        CalculoDiferencial1         matlab.ui.control.ToggleButton
        SoluoExataEditFieldLabel    matlab.ui.control.Label
        SolExata1                   matlab.ui.control.EditField
        MENU2VR                     matlab.ui.container.Tab
        CalcularBotao2VR            matlab.ui.control.Button
        ayLabel                     matlab.ui.control.Label
        ay_field2                   matlab.ui.control.NumericEditField
        byLabel                     matlab.ui.control.Label
        by_field2                   matlab.ui.control.NumericEditField
        nyLabel                     matlab.ui.control.Label
        ny_field2                   matlab.ui.control.NumericEditField
        axLabel_2                   matlab.ui.control.Label
        ax_field2                   matlab.ui.control.NumericEditField
        bxLabel_2                   matlab.ui.control.Label
        bx_field2                   matlab.ui.control.NumericEditField
        nxLabel_2                   matlab.ui.control.Label
        nx_field2                   matlab.ui.control.NumericEditField
        TitleMENU2                  matlab.ui.control.Label
        SubtitleMENU2               matlab.ui.control.Label
        CDI_Botoes2                 matlab.ui.container.ButtonGroup
        CalculoIntegral2            matlab.ui.control.ToggleButton
        CalculoDiferencial2         matlab.ui.control.ToggleButton
        InsiraumafunozfxyLabel      matlab.ui.control.Label
        funcao2                     matlab.ui.control.EditField
        SoluoExataEditFieldLabel_2  matlab.ui.control.Label
        SolExata2                   matlab.ui.control.EditField
        Representacao2              matlab.ui.control.DropDown
        ONE_VR                      matlab.ui.container.Tab
        Label1                      matlab.ui.control.Label
        SubLabel1                   matlab.ui.control.Label
        Tabela1                     matlab.ui.control.Table
        Grelha1                     matlab.ui.control.CheckBox
        Subtitle1                   matlab.ui.control.Label
        FuncaoAtual1                matlab.ui.control.Label
        LabelAreaTrapz              matlab.ui.control.Label
        ValorAreaTrapz              matlab.ui.control.Label
        LabelErroTrapz              matlab.ui.control.Label
        ValorErroTrapz              matlab.ui.control.Label
        LabelAreaSimpson            matlab.ui.control.Label
        ValorAreaSimpson            matlab.ui.control.Label
        LabelErroSimpson            matlab.ui.control.Label
        ValorErroSimpson            matlab.ui.control.Label
        LabelAreaExata              matlab.ui.control.Label
        ValorAreaExata              matlab.ui.control.Label
        Graph1                      matlab.ui.control.UIAxes
        TWO_VR                      matlab.ui.container.Tab
        Label2                      matlab.ui.control.Label
        SubLabel2                   matlab.ui.control.Label
        Grelha2                     matlab.ui.control.CheckBox
        Subtitle2                   matlab.ui.control.Label
        FuncaoAtual2                matlab.ui.control.Label
        Harmonica2                  matlab.ui.control.Label
        Graph2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularBotao1VR
        function CalcularBotao1VRButtonPushed(app, event)

            syms y(x) % Criar uma função simbólica
           
            %%%%% Verificação do Input %%%%%
            strF = app.funcao1.Value; % Obter o valor no campo da função f
            
            f = @(x) eval(vectorize(strF)); % Definir a nossa função f
            try % Testar se a função inserida é válida
                f(x);
            catch
                errordlg("Introduza uma função em x","Erro");
                return
            end
            
            
            a = app.a_field1.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field1.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field1.Value; % Obter o valor do campo N
            if (not(isscalar(n)) || n < 1)
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
            
            % Mostra o separador "Função real de 1 variável real"
            app.TabGroup.SelectedTab = app.ONE_VR; 
            
            cla(app.Graph1); % Limpar o gráfico
            addpath Métodos\; % Adicionar a pasta dos métodos
            
            botaoSelecionado = app.CDI_Botoes1.SelectedObject.Text; % Derivar/Integrar
            metodo = app.Metodos1.Value; % Método selecionado
            
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    app.Subtitle1.Text = botaoSelecionado;
                case 'Cálculo Integral'
                    app.Subtitle1.Text = botaoSelecionado;
            end
            
            app.FuncaoAtual1.Text = strcat("Função: ", strF, "    a: ", string(a), "    b: ", string(b), "    Iterações: ", string(n), "    Método: ", metodo);
            
            h = (b-a)/n; % Amplitude dos subintervalos
            
            syms y(x) % Criação de uma função simbólica
            switch botaoSelecionado
                % Se o utilizador tiver escolhido derivar a função
                case 'Cálculo Diferencial'
                    
                    app.LabelAreaExata.Visible = 'off';
                    app.ValorAreaExata.Visible = 'off';
                    app.ValorAreaTrapz.Visible = 'off';
                    app.LabelAreaTrapz.Visible = 'off';
                    app.ValorAreaTrapz.Visible = 'off';
                    app.LabelErroTrapz.Visible = 'off';
                    app.ValorErroTrapz.Visible = 'off';
                    app.LabelAreaSimpson.Visible = 'off';
                    app.ValorAreaSimpson.Visible = 'off';
                    app.LabelErroSimpson.Visible = 'off';
                    app.ValorErroSimpson.Visible = 'off';
                    
                    st = 0:1:n; % Array com as nº das iterações de 1 a n
                    t = a:h:b; % Valores que a variável X pode tomar
                    d = diff(f,x); % Derivar a função em ordem a x
                    g = @(x) eval(vectorize(d)); % Converter o resultado obtido por diff numa função
                    fplot(app.Graph1,f,'-b','LineWidth',1); % Plot da função
                    hold(app.Graph1, "on");
                    fplot(app.Graph1,g,'--r','LineWidth',1); % Plot da derivada da função
                    hold(app.Graph1, "on");
                    xlim(app.Graph1, [a b]);
                    % Se o método escolhido for "D.F. Progressivas (2 pontos)
                    if(metodo == "D.F. Progressivas (2 pontos)")
                        [t,~,dydx] = DF2Progressivas(f,a,b,h); % Utiliza o método e obtemos os valores que x pode tomar e a derivada aproximada para cada x
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1); % Plot do gráfico da derivida obtida pelo método anterior
                        hold(app.Graph1, "on");
                        func = f(t); % Valores para Y da função introduzida, em todos os X (vetor)
                        derivada = g(t); % Valores para Y da derivada da função introduzida, em todos os X (vetor)
                        dfErro = derivada - dydx; % Erro do método
                        dfErro = abs(dfErro); % Valor absoluto do erro
                        legend(app.Graph1,'Função','Derivada','DF2P','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFP2', 'Erro DFP2'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela; % Mostrar o vetor tabela na tabela
                    elseif(metodo == "D.F. Regressivas (2 pontos)")
                        [t,~,dydx] = DF2Regressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF2R','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFR2','Erro DFR2'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Progressivas (3 pontos)")
                        [t,~,dydx] = DF3Progressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3P','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFP3','Erro DFP3'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Regressivas (3 pontos)")
                        [t,~,dydx] = DF3Regressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3R','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFR3','Erro DFR3'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Centradas (3 pontos)")
                        [t,~,dydx] = DF3Centradas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3C','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFC3','Erro DFC3'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. 2ª Derivada (3 pontos)")
                        [t,~,dydx] = DF3Centradas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3D','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DF3D','Erro DF3D'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "Todos")
                        [t,~,dydxDF2P] = DF2Progressivas(f,a,b,h);
                        [~,~,dydxDF2R] = DF2Regressivas(f,a,b,h);
                        [~,~,dydxDF3P] = DF3Progressivas(f,a,b,h);
                        [~,~,dydxDF3R] = DF3Regressivas(f,a,b,h);
                        [~,~,dydxDF3C] = DF3Centradas(f,a,b,h);
                        [~,~,dydxDF3D] = DF3Derivada(f,a,b,h);
                        plot(app.Graph1,t,dydxDF2P,'-mo','LineWidth',1);
                        plot(app.Graph1,t,dydxDF2R,'-go','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3P,'-co','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3R,'-ko','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3C,'--m','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3D,'--g','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErroDF2P = derivada - dydxDF2P;
                        dfErroDF2R = derivada - dydxDF2R;
                        dfErroDF3P = derivada - dydxDF3P;
                        dfErroDF3R = derivada - dydxDF3R;
                        dfErroDF3C = derivada - dydxDF3C;
                        dfErroDF3D = derivada - dydxDF3D;
                        dfErroDF2P = abs(dfErroDF2P);
                        dfErroDF2R = abs(dfErroDF2R);
                        dfErroDF3P = abs(dfErroDF3P);
                        dfErroDF3R = abs(dfErroDF3R);
                        dfErroDF3C = abs(dfErroDF3C);
                        dfErroDF3D = abs(dfErroDF3D);
                        
                        legend(app.Graph1,'Função','Derivada','DF2P','DF2R','DF3P','DF3R','DF3C','DF3D','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydxDF2P.',dfErroDF2P.',dydxDF2R.',dfErroDF2R.',dydxDF3P.',dfErroDF3P.',dydxDF3R.',dfErroDF3R.',dydxDF3C.',dfErroDF3C.',dydxDF3D.',dfErroDF3D.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DF2P','Erro DF2P','DF2R','Erro DF2R','DF3P','Erro DF3P','DF3R','Erro DF3R','DF3C','Erro DF3C','DF3D','Erro DF3D'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela; 
                    end

                % Se o utilizador tiver escolhido integrar a função
                case 'Cálculo Integral'
                    st = 0:1:n; % Array com as nº das iterações de 1 a n
                    t = a:h:b; % Valores que a variável X pode tomar
                    d = int(f,x); % Integrar a função em ordem a X
                    g = @(x) eval(vectorize(d)); % Converter o resultado obtido por diff numa função
                    fplot(app.Graph1,f,'-bo','LineWidth',1); % Plot da função inserida
                    hold(app.Graph1, "on");
                    fplot(app.Graph1,g,'--r','LineWidth',1); % Plot da derivada da função
                    hold(app.Graph1, "on");
                    xlim(app.Graph1, [a b]);
                    func = f(t); % Valores para Y da função introduzida, em todos os X (vetor)
                    primitiva = g(t); % Valores para Y da primitiva da função, em todos os X (vetor)
                    area(app.Graph1,t,func); % Plot da área por baixo do gráfico
                    integral = int(f,x,[a b]); % Integral definido de A a B (solução exata)
                    areaExata = vpa(integral); % Conversão do valor anterior numa área
                   
                    % Transformação de um número numa string
                    app.ValorAreaExata.Text = num2str(round(double(areaExata),4));
                    app.LabelAreaExata.Visible = 'on';
                    app.ValorAreaExata.Visible = 'on';
                        
                    % Se o método escolhido for "D.F. Progressivas (2 pontos)
                    if(metodo == "Regra dos Trapézios")
                        app.LabelAreaTrapz.Visible = 'on';
                        app.ValorAreaTrapz.Visible = 'on';
                        app.LabelErroTrapz.Visible = 'off';
                        app.ValorErroTrapz.Visible = 'off';
                        app.LabelAreaSimpson.Visible = 'off';
                        app.ValorAreaSimpson.Visible = 'off';
                        app.LabelErroSimpson.Visible = 'off';
                        app.ValorErroSimpson.Visible = 'off';
                        
                        areaFuncao = RTrapezios(f,a,b,n); % Cálculo da área aproximada com a Regra dos Trapézios
                        erroArea = abs(areaExata - areaFuncao); % Cálculo do erro da área (valor absoluto)
                        app.ValorAreaTrapz.Text = num2str(round(double(areaFuncao),4));
                        app.ValorErroTrapz.Text = num2str(round(double(erroArea),4));
                    elseif(metodo == "Regra de Simpson")
                        app.LabelAreaTrapz.Visible = 'off';
                        app.ValorAreaTrapz.Visible = 'off';
                        app.LabelErroTrapz.Visible = 'off';
                        app.ValorErroTrapz.Visible = 'off';
                        app.LabelAreaSimpson.Visible = 'on';
                        app.ValorAreaSimpson.Visible = 'on';
                        app.LabelErroSimpson.Visible = 'on';
                        app.ValorErroSimpson.Visible = 'on';
                        
                        areaFuncao = RSimpson(f,a,b,n);
                        erroArea = abs(areaExata - areaFuncao);
                        app.ValorAreaSimpson.Text = num2str(round(double(areaFuncao),4));
                        app.ValorErroSimpson.Text = num2str(round(double(erroArea),4));
                    elseif(metodo == "Todos")
                        app.LabelAreaTrapz.Visible = 'on';
                        app.ValorAreaTrapz.Visible = 'on';
                        app.LabelErroTrapz.Visible = 'on';
                        app.ValorErroTrapz.Visible = 'on';
                        app.LabelAreaSimpson.Visible = 'on';
                        app.ValorAreaSimpson.Visible = 'on';
                        app.LabelErroSimpson.Visible = 'on';
                        app.ValorErroSimpson.Visible = 'on';
                        
                        areaFuncaoT = RTrapezios(f,a,b,n);
                        areaFuncaoS = RSimpson(f,a,b,n);
                        erroAreaT = abs(areaExata - areaFuncaoT);
                        erroAreaS = abs(areaExata - areaFuncaoS);

                        app.ValorAreaTrapz.Text = num2str(round(double(areaFuncaoT),4));
                        app.ValorErroTrapz.Text = num2str(round(double(erroAreaT),4));
                        
                        app.ValorAreaSimpson.Text = num2str(round(double(areaFuncaoS),4));
                        app.ValorErroSimpson.Text = num2str(round(double(erroAreaS),4));
                        
                    end
                    legend(app.Graph1,'Função','Primitiva','Location','northwest'); % Legenda do gráfico
                    tabela = [st.',t.',func.',primitiva.']; % Criação da tabela
                    set(app.Tabela1, 'columnname', {'Iteração','t','Função','Primitiva'}); % Nomes das colunas da tabela
                    app.Tabela1.Data = tabela; % Mostrar o vetor tabela na tabela
            end
        end

        % Button pushed function: CalcularBotao2VR
        function CalcularBotao2VRButtonPushed(app, event)

            syms f(x,y) % Criar uma função simbólica
            
            %%%%% Verificação do Input %%%%%
            strFxy = app.funcao2.Value; % Obter o valor no campo da função f
            f = @(x,y) eval(vectorize(strFxy)); % Definir a nossa função f
            
            dx2 = diff(f,x,2); % Segunda derivada em ordem a X
            dy2 = diff(f,y,2); % Segunda derivada em ordem a Y
            
            % Ver se uma função é harmónica
            if(dx2 + dy2 == 0) % Se a soma das derivadas de segundo grau for igual a 0 então a função é harmónica
                app.Harmonica2.Text = "A função é harmónica";
                app.Harmonica2.FontColor = "g";
            else
                app.Harmonica2.Text = "A função não é harmónica";
                app.Harmonica2.FontColor = "r";                               
            end
            
            a = app.ax_field2.Value; % Obter o valor do campo AX
            if (not(isscalar(a) && isreal(a))) % O valor AX tem de ser real
                errordlg("O número introduzido para ax é inválido","Erro");
                return
            end
            c = app.ay_field2.Value; % Obter o valor do campo AY
            if (not(isscalar(c) && isreal(c))) % O valor AY tem de ser real
                errordlg("O número introduzido para ay é inválido","Erro");
                return
            end
            
            b = app.bx_field2.Value; % Obter o valor do campo BX
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor BX tem de ser real e maior que AX
                errordlg("O número introduzido para bx é inválido","Erro");
                return
            end
            d = app.by_field2.Value; % Obter o valor do campo BY
            if (not(isscalar(d) && isreal(d) && b>c)) % O valor BY tem de ser real e maior que AY
                errordlg("O número introduzido para by é inválido","Erro");
                return
            end
        
            n1 = app.nx_field2.Value; % Obter o valor do campo NX
            if (not(isscalar(n1) && n1 > 1))
                errordlg("O número introduzido para nx é inválido","Erro");
                return
            end
            n2 = app.ny_field2.Value; % Obter o valor do campo NY
            if (not(isscalar(n2) && n2 > 1))
                errordlg("O número introduzido para ny é inválido","Erro");
                return
            end
            
            % Mostra o separador "Função real de 2 variáveis reais"
            app.TabGroup.SelectedTab = app.TWO_VR;
            
            cla(app.Graph2); % Limpa o gráfico
            addpath Métodos\; % Adiciona a pasta dos métodos
            
            representacao = app.Representacao2.Value;
            
            app.FuncaoAtual2.Text = strcat("Função: ", strFxy, "       ax: ", string(a), "    bx: ", string(b), "    nx : ", string(n1),"       ay: ", string(c), "    by: ", string(d), "    ny : ", string(n2),"       (",representacao,")");
            
            h1 = (b-a)/n1; % Amplitude do subintervalo para X
            h2 = (d-c)/n2; % Amplitude do subintervalo para Y
           
            switch representacao
                % Se o utilizador tiver escolhido representar a superfície usando Surface
                case 'Surface'
                    [x,y] = meshgrid(a:h1:b, c:h2:d); % Criação de um array com os valores de X e Y
                    z = f(x,y); % Cálculo da função para todos os valores de X e Y
                    surf(app.Graph2, x,y,z); % Mostra a Surface no gráfico
                % Se o utilizador tiver escolhido representar a superfície usando Mesh
                case 'Mesh' % Criação de um array com os valores de X e Y
                    [x,y] = meshgrid(a:h1:b, c:h2:d);
                    z = f(x,y); % Cálculo da função para todos os valores de X e Y
                    mesh(app.Graph2, x,y,z); % Mostra a Mesh no gráfico
            end
        end

        % Selection changed function: CDI_Botoes1
        function CDI_Botoes1SelectionChanged(app, event)
            value = app.funcao1.Value;
            selectedButton = app.CDI_Botoes1.SelectedObject.Text;
            dropdown = app.Metodos1;
            
            syms y(x) % Criação de uma função simbólica
            f = @(x) eval(vectorize(value)); % Transformar a string numa função
            try
                f(x); % Verificar se a string é uma função válida
            catch
                errordlg("Introduza uma função em x válida","Erro");
                app.SolExata1.Value = "";
                return
            end
            
            % Aleteração do conteúdo do dropdown quando o método é alterado
            switch selectedButton
                case 'Cálculo Diferencial'
                    dropdown.Items = {  
                                            'D.F. Progressivas (2 pontos)'
                                            'D.F. Regressivas (2 pontos)'
                                            'D.F. Progressivas (3 pontos)'
                                            'D.F. Regressivas (3 pontos)'
                                            'D.F. Centradas (3 pontos)'
                                            'D.F. 2ª Derivada (3 pontos)'
                                            'Todos'
                                     };
                    
                    d = diff(f,x); % Calcula a derivada 
                    app.SolExata1.Value = string(d); % Mostra a derivada na caixa abaixo
                case 'Cálculo Integral'                    
                    dropdown.Items = {  
                                            'Regra dos Trapézios'
                                            'Regra de Simpson'
                                            'Todos'
                                     };
                    
                    d = int(f,x);
                    app.SolExata1.Value = string(d);
            end
        end

        % Selection changed function: CDI_Botoes2
        function CDI_Botoes2SelectionChanged(app, event)
            value = app.funcao2.Value;
            selectedButton = app.CDI_Botoes2.SelectedObject.Text;
            
            syms f(x,y) % Criação de uma função simbólica
            f = @(x,y) eval(vectorize(value)); % Converter a string numa função
            try
                f(x,y); % Verificar se a função introduzida é válida
            catch
                errordlg("Introduza uma função em x e y válida","Erro");
                app.SolExata2.Value = "";
                return
            end
            
            % Mostra a primitiva/derivada quando o utilizador acaba de escrever
            switch selectedButton
                case 'Cálculo Diferencial'
                    dx = diff(f,x);
                    dy = diff(f,y);
                    d = dx + dy;
                    app.SolExata2.Value = string(d);
                case 'Cálculo Integral'                    
                    d = int(f,x,y);
                    app.SolExata2.Value = string(d);
            end
        end

        % Value changed function: funcao1
        function funcao1ValueChanged(app, event)
            value = app.funcao1.Value;
            botaoSelecionado = app.CDI_Botoes1.SelectedObject.Text;
            
            syms y(x) 
            f = @(x) eval(vectorize(value));
            try
                f(x);
            catch
                errordlg("Introduza uma função em x válida","Erro");
                app.SolExata1.Value = "";
                return
            end
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    d = diff(f,x);
                    app.SolExata1.Value = string(d); 
                case 'Cálculo Integral'
                    d = int(f,x);
                    app.SolExata1.Value = string(d);
            end
        end

        % Value changed function: funcao2
        function funcao2ValueChanged(app, event)
            value = app.funcao2.Value;
            botaoSelecionado = app.CDI_Botoes2.SelectedObject.Text;
            
            syms f(x,y)
            f = @(x,y) eval(vectorize(value));
            try
                f(x,y);
            catch
                errordlg("Introduza uma função em x e y válida","Erro");
                app.SolExata2.Value = "";
                return
            end
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    dx = diff(f,x);
                    dy = diff(f,y);
                    d = dx + dy;
                    app.SolExata2.Value = string(d);
                case 'Cálculo Integral'
                    d = int(f,x,y);
                    app.SolExata2.Value = string(d);
            end
        end

        % Value changed function: Grelha1
        function Grelha1ValueChanged(app, event)
            value = app.Grelha1.Value;
            switch value
                case 1
                    grid(app.Graph1, "on");
                case 0
                    grid(app.Graph1, "off");
            end
        end

        % Value changed function: Grelha2
        function Grelha2ValueChanged(app, event)
            value = app.Grelha2.Value;
            switch value
                case 1
                    grid(app.Graph2, "on");
                case 0
                    grid(app.Graph2, "off");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'Máquina CDI';
            app.TabGroup = uitabgroup(app.UI);
            app.MENU1VR = uitab(app.TabGroup);
            app.MENU1VR.Title = 'Menu (1 variável real)';
            app.TitleMENU1 = uilabel(app.MENU1VR);
            app.TitleMENU1.Text = 'Máquina';
            app.SubtitleMENU1 = uilabel(app.MENU1VR);
            app.SubtitleMENU1.Text = 'Cáculo Diferencial e Integral';
            app.InsiraumafunoyfxLabel = uilabel(app.MENU1VR);
            app.InsiraumafunoyfxLabel.Text = ' Insira uma função: y = f(x)';
            app.funcao1 = uieditfield(app.MENU1VR, 'text');
            app.funcao1.ValueChangedFcn = createCallbackFcn(app, @funcao1ValueChanged, true);
            app.funcao1.Value = 'sin(x)';
            app.aEditFieldLabel = uilabel(app.MENU1VR);
            app.aEditFieldLabel.Text = 'a';
            app.a_field1 = uieditfield(app.MENU1VR, 'numeric');
            app.bEditFieldLabel = uilabel(app.MENU1VR);
            app.bEditFieldLabel.Text = 'b';
            app.b_field1 = uieditfield(app.MENU1VR, 'numeric');
            app.b_field1.Value = 6.286;
            app.nEditFieldLabel = uilabel(app.MENU1VR);
            app.nEditFieldLabel.Text = 'n';
            app.n_field1 = uieditfield(app.MENU1VR, 'numeric');
            app.n_field1.Value = 20;
            app.CalcularBotao1VR = uibutton(app.MENU1VR, 'push');
            app.CalcularBotao1VR.ButtonPushedFcn = createCallbackFcn(app, @CalcularBotao1VRButtonPushed, true);
            app.CalcularBotao1VR.Text = 'Calcular (1 variável real)';
            app.MtodosDropDownLabel = uilabel(app.MENU1VR);
            app.MtodosDropDownLabel.Text = 'Métodos';
            app.Metodos1 = uidropdown(app.MENU1VR);
            app.Metodos1.Items = {'D.F. Progressivas (2 pontos)', 'D.F. Regressivas (2 pontos)', 'D.F. Progressivas (3 pontos)', 'D.F. Regressivas (3 pontos)', 'D.F. Centradas (3 pontos)', 'D.F. 2ª Derivada (3 pontos)', 'Todos'};
            app.Metodos1.Value = 'D.F. Progressivas (2 pontos)';
            app.CDI_Botoes1 = uibuttongroup(app.MENU1VR);
            app.CDI_Botoes1.SelectionChangedFcn = createCallbackFcn(app, @CDI_Botoes1SelectionChanged, true);
            app.CalculoIntegral1 = uitogglebutton(app.CDI_Botoes1);
            app.CalculoIntegral1.Text = 'Cálculo Diferencial';
            app.CalculoIntegral1.Value = true;
            app.CalculoDiferencial1 = uitogglebutton(app.CDI_Botoes1);
            app.CalculoDiferencial1.Text = 'Cálculo Integral';
            app.SoluoExataEditFieldLabel = uilabel(app.MENU1VR);
            app.SoluoExataEditFieldLabel.Text = 'Solução Exata';
            app.SolExata1 = uieditfield(app.MENU1VR, 'text');
            app.SolExata1.Editable = 'off';
            app.SolExata1.Value = 'cos(x)';
            app.MENU2VR = uitab(app.TabGroup);
            app.MENU2VR.Title = 'Menu (2 variáveis reais)';
            app.CalcularBotao2VR = uibutton(app.MENU2VR, 'push');
            app.CalcularBotao2VR.ButtonPushedFcn = createCallbackFcn(app, @CalcularBotao2VRButtonPushed, true);
            app.CalcularBotao2VR.Text = 'Calcular (2 variáveis reais)';
            app.ayLabel = uilabel(app.MENU2VR);
            app.ayLabel.Text = 'ay';
            app.ay_field2 = uieditfield(app.MENU2VR, 'numeric');
            app.ay_field2.Value = -2;
            app.byLabel = uilabel(app.MENU2VR);
            app.byLabel.Text = 'by';
            app.by_field2 = uieditfield(app.MENU2VR, 'numeric');
            app.by_field2.Value = 2;
            app.nyLabel = uilabel(app.MENU2VR);
            app.nyLabel.Text = 'ny';
            app.ny_field2 = uieditfield(app.MENU2VR, 'numeric');
            app.ny_field2.Value = 20;
            app.axLabel_2 = uilabel(app.MENU2VR);
            app.axLabel_2.Text = 'ax';
            app.ax_field2 = uieditfield(app.MENU2VR, 'numeric');
            app.ax_field2.Value = -2;
            app.bxLabel_2 = uilabel(app.MENU2VR);
            app.bxLabel_2.Text = 'bx';
            app.bx_field2 = uieditfield(app.MENU2VR, 'numeric');
            app.bx_field2.Value = 2;
            app.nxLabel_2 = uilabel(app.MENU2VR);
            app.nxLabel_2.Text = 'nx';
            app.nx_field2 = uieditfield(app.MENU2VR, 'numeric');
            app.nx_field2.Value = 20;
            app.TitleMENU2 = uilabel(app.MENU2VR);
            app.TitleMENU2.Text = 'Máquina';
            app.SubtitleMENU2 = uilabel(app.MENU2VR);
            app.SubtitleMENU2.Text = 'Representação gráfica';
            app.CDI_Botoes2 = uibuttongroup(app.MENU2VR);
            app.CDI_Botoes2.SelectionChangedFcn = createCallbackFcn(app, @CDI_Botoes2SelectionChanged, true);
            app.CalculoIntegral2 = uitogglebutton(app.CDI_Botoes2);
            app.CalculoIntegral2.Text = 'Cálculo Diferencial';
            app.CalculoIntegral2.Value = true;
            app.CalculoDiferencial2 = uitogglebutton(app.CDI_Botoes2);
            app.CalculoDiferencial2.Text = 'Cálculo Integral';
            app.InsiraumafunozfxyLabel = uilabel(app.MENU2VR);
            app.InsiraumafunozfxyLabel.Text = ' Insira uma função: z = f(x,y)';
            app.funcao2 = uieditfield(app.MENU2VR, 'text');
            app.funcao2.ValueChangedFcn = createCallbackFcn(app, @funcao2ValueChanged, true);
            app.funcao2.Value = 'x^2+y^2';
            app.SoluoExataEditFieldLabel_2 = uilabel(app.MENU2VR);
            app.SoluoExataEditFieldLabel_2.Text = 'Solução Exata';
            app.SolExata2 = uieditfield(app.MENU2VR, 'text');
            app.SolExata2.Editable = 'off';
            app.SolExata2.Value = '2*x + 2*y';
            app.Representacao2 = uidropdown(app.MENU2VR);
            app.Representacao2.Items = {'Surface', 'Mesh'};
            app.Representacao2.Value = 'Surface';
            app.ONE_VR = uitab(app.TabGroup);
            app.ONE_VR.Title = 'Função real de 1 variável real: y = f(x)';
            app.Label1 = uilabel(app.ONE_VR);
            app.Label1.Text = 'Função real de 1 variável real';
            app.SubLabel1 = uilabel(app.ONE_VR);
            app.SubLabel1.Text = 'y = f(x)';
            app.Tabela1 = uitable(app.ONE_VR);
            app.Tabela1.ColumnName = {''; ''; ''; ''};
            app.Tabela1.RowName = {};
            app.Grelha1 = uicheckbox(app.ONE_VR);
            app.Grelha1.ValueChangedFcn = createCallbackFcn(app, @Grelha1ValueChanged, true);
            app.Grelha1.Text = 'Grelha';
            app.Subtitle1 = uilabel(app.ONE_VR);
            app.Subtitle1.Text = 'Cálculo Diferencial e Integral';
            app.FuncaoAtual1 = uilabel(app.ONE_VR);
            app.FuncaoAtual1.Text = 'Aceda ao separador "Menu 1VR" para começar';
            app.LabelAreaTrapz = uilabel(app.ONE_VR);
            app.LabelAreaTrapz.Visible = 'off';
            app.LabelAreaTrapz.Text = 'Regra dos Trapézios:';
            app.ValorAreaTrapz = uilabel(app.ONE_VR);
            app.ValorAreaTrapz.Visible = 'off';
            app.ValorAreaTrapz.Text = '3921.1212';
            app.LabelErroTrapz = uilabel(app.ONE_VR);
            app.LabelErroTrapz.Visible = 'off';
            app.LabelErroTrapz.Text = 'Erro:';
            app.ValorErroTrapz = uilabel(app.ONE_VR);
            app.ValorErroTrapz.Visible = 'off';
            app.ValorErroTrapz.Text = '0.0003';
            app.LabelAreaSimpson = uilabel(app.ONE_VR);
            app.LabelAreaSimpson.Visible = 'off';
            app.LabelAreaSimpson.Text = 'Regra de Simpson:';
            app.ValorAreaSimpson = uilabel(app.ONE_VR);
            app.ValorAreaSimpson.Visible = 'off';
            app.ValorAreaSimpson.Text = '3921.1212';
            app.LabelErroSimpson = uilabel(app.ONE_VR);
            app.LabelErroSimpson.Visible = 'off';
            app.LabelErroSimpson.Text = 'Erro:';
            app.ValorErroSimpson = uilabel(app.ONE_VR);
            app.ValorErroSimpson.Visible = 'off';
            app.ValorErroSimpson.Text = '0.0003';
            app.LabelAreaExata = uilabel(app.ONE_VR);
            app.LabelAreaExata.Visible = 'off';
            app.LabelAreaExata.Text = 'Exata';
            app.ValorAreaExata = uilabel(app.ONE_VR);
            app.ValorAreaExata.Visible = 'off';
            app.ValorAreaExata.Text = '3921.1212';
            app.Graph1 = uiaxes(app.ONE_VR);
            zlabel(app.Graph1, 'Z')
            app.TWO_VR = uitab(app.TabGroup);
            app.TWO_VR.Title = 'Função real de 2 variáveis reais: z = f(x,y)';
            app.Label2 = uilabel(app.TWO_VR);
            app.Label2.Text = 'Função real de 2 variáveis reais';
            app.SubLabel2 = uilabel(app.TWO_VR);
            app.SubLabel2.Text = 'z = f(x,y)';
            app.Grelha2 = uicheckbox(app.TWO_VR);
            app.Grelha2.ValueChangedFcn = createCallbackFcn(app, @Grelha2ValueChanged, true);
            app.Grelha2.Text = 'Grelha';
            app.Subtitle2 = uilabel(app.TWO_VR);
            app.Subtitle2.Text = 'Representação gráfica';
            app.FuncaoAtual2 = uilabel(app.TWO_VR);
            app.FuncaoAtual2.Text = 'Aceda ao separador "Menu 2VR" para começar';
            app.Harmonica2 = uilabel(app.TWO_VR);
            app.Harmonica2.Text = '';
            app.Graph2 = uiaxes(app.TWO_VR);
            zlabel(app.Graph2, 'Z')
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end